classdef facture_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ReturnButton        matlab.ui.control.Button
        UploadScanButton    matlab.ui.control.Button
        BoneXrayLabel       matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
        XRayDiagnosisLabel  matlab.ui.control.Label
        AnalyseXRayButton   matlab.ui.control.Button
        Label               matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        ResetButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            %%Once the Button is pressed it will take the user to the 
            %%Main Menu screen and will delete the brain tumor dection window
            
            Main_Menu < matlab.apps.AppBase; %Opening scenario 1
            delete(app.UIFigure); %Closing Main Menu
        end

        % Button pushed function: UploadScanButton
        function UploadScanButtonPushed(app, event)
            global xray_img
            
            %Allows the user to uploadthe brain MRI Scan they want a tumor
            %to be classified and detected in if present
          
            [rawname, rawpath] = uigetfile({'*.jpg';'*.jpeg';'*.png'}, 'Select Image Data');
            mri_scan_path = [rawpath, rawname];
            xray_img = imread(mri_scan_path); %Makes the inputted picture usable in the program
            bw_xray = im2bw(xray_img,0.3);
            imshow(xray_img, 'Parent',app.UIAxes); %Makes the Default MRi Image Appear in the App
            
            % Top middle point = TMP
            
            [TMP_y, TMP_x] = size(xray_img);
            
            %Dimesnions of the selected image
            imageLength = TMP_y; %Ycoordinate
            imageWidth = TMP_x/3;
            
            %Centre around the x axis
            cofx = imageWidth/2;
            %Centre around the y axis
            cofy = imageLength/2;
            
           
            [PW_x, PW_y] = find(bw_xray, 1);
            hold(app.UIAxes, "on") 
            plot(app.UIAxes, PW_x, PW_y, 'r.', 'MarkerSize',5,'LineWidth',5);
            hold(app.UIAxes, "off") 
       
            
            %DV stands for direction vector
            DV_CA = [0, cofy]; %Direction vector C from A
            DV_CB = [cofx - PW_x, cofy - PW_y]; %Direction vector C from B
            
            angle = acosd((DV_CA(2)*DV_CB(2))/(DV_CA(2) * sqrt(DV_CB(1).^2 + DV_CB(2).^2)));
            
            angle = round(angle,1);
            
            
            %xray_img = imrotate(xray_img,angle);
            %imshow(xray_img, 'Parent',app.UIAxes); %Makes the Default MRi Image Appear in the App


        end

        % Callback function
        function SaturationFactorSliderValueChanged(app, event)
            value = app.SaturationFactorSlider.Value;
            
        end

        % Callback function
        function SaturationFactorSliderValueChanging(app, event)
            global xray_img
            global bw_xray_scan
            
            changingValue = event.Value;
          
            bw_xray_scan = im2bw(xray_img, app.DensityFactorSlider_2.Value); %Changes the image to black and white, also changes saturation
            
            imshow(xray_img, 'Parent',app.UIAxes); %Makes the Default MRi Image Appear in the App
            
        end

        % Button pushed function: AnalyseXRayButton
        function AnalyseXRayButtonPushed(app, event)
            global xray_img
            
            %% The following variables set up the conditions to apply Hough Transformation for Long Bone Fracture detection
            ImgSigmaBlur = 1.8; %This is the scale factor at which noise will be reduced in the image
            MinHoughPeakDistance = 5;% Minimum distance between peaks in Hough transformation angle detection
            HoughConvolutionLength = 40; % The length of a line to detect long bone regions
            HoughConvolutioonDilste = 2; % Amount of dilate kernal for bone detection
            BreakLineTolerance = 0.25; % Percentage tolerance for end of bone detection
            BreakPointDilate = 6; %Amount of Dilate detected bone end points
            
            %%%%%%%%%%%
            
            %%The try -> catch statements tries to turn the x-ray image into grayscale if it isn't already
            
            try
                xray_img = rgb2gray(xray_img);
            catch 
                %Do nothing
            end
            
            %Removing noise from the image
            xray_img = imfilter(xray_img, fspecial('gaussian', 10, ImgSigmaBlur), 'symmetric');
            
            % Do edge detection to find bone edges in image
            % Filter out all but the two longest lines
            % This feature may need to be changed if break is not in middle of bone
            
            boneEdge = edge(xray_img, 'canny'); 
            boneEdge = bwmorph(boneEdge, 'close');
            edgeRegs = regionprops(boneEdge, 'Area','PixelIdxList');
            AreaList = sort(vertcat(edgeRegs.Area), 'descend');
            edgeRegs(~ismember(vertcat(edgeRegs.Area), AreaList(1:2))) = [];
            edgeImg = zeros(size(xray_img, 1), size(xray_img,2));
            edgeImg(vertcat(edgeRegs.PixelIdxList)) = 1;
            
            % Do hough transform on edge image to find angles at which bone pieces are
            % found
            % Use max value of Hough transform vs angle to find angles at which lines
            % are oriented.  If there is more than one major angle contribution there
            % will be two peaks detected but only one peak if there is only one major
            % angle contribution (ie peaks here = number of located bones = Number of
            % breaks + 1)
            
            [H,T,R] = hough(edgeImg,'RhoResolution',1,'Theta',-90:2:89.5);
            maxHough = max(H, [], 1);
            HoughThresh = (max(maxHough) - min(maxHough))/2 + min(maxHough);
            [~, HoughPeaks] = findpeaks(maxHough,'MINPEAKHEIGHT',HoughThresh, 'MinPeakDistance', MinHoughPeakDistance);
            
            plot(app.UIAxes2,T, maxHough)
            
            hold(app.UIAxes2, 'On')
            plot(app.UIAxes2, [min(T) max(T)], [HoughThresh, HoughThresh], 'r');
            plot(app.UIAxes2, T(HoughPeaks), maxHough(HoughPeaks), 'rx', 'MarkerSize', 12, 'LineWidth', 2);
            hold(app.UIAxes2, 'Off')
            legend(app.UIAxes2,{'Max Hough Transform', 'Hough Peak Threshold', 'Detected Peak'});


            %%DETECTING WETHER OR NOT A LONG BONE FRACTURE HAS OCCURED
            maxPeak = maxHough(HoughPeaks); %The peak value
            primePeak = maxHough(1); %The Max Hough at -90 Theta
            
            if numel(maxPeak)>= 3
                app.Label.Text = {'This X-ray contains multiple bone fractures'};
                
            elseif numel(maxPeak) >=2
                app.Label.Text = {'This X-ray 1 or more bone fractures'};
                
            elseif numel(maxPeak) == 1 && maxPeak >= 190
                app.Label.Text = {'This X-ray Contains a bone Fracture'};
            
            elseif primePeak < maxPeak
                app.Label.Text = "The bone in this X-Ray doesn't have a fracture";
                
            else
                app.Label.Text = "The bone in this X-Ray doesn't have a fracture";
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            facture_detection < matlab.apps.AppBase;
            delete(app.UIFigure); %Closing Main Menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.UploadScanButton = uibutton(app.UIFigure, 'push');
            app.UploadScanButton.ButtonPushedFcn = createCallbackFcn(app, @UploadScanButtonPushed, true);
            app.UploadScanButton.Text = {'Upload Scan'; ''};
            app.BoneXrayLabel = uilabel(app.UIFigure);
            app.BoneXrayLabel.Text = 'Bone X-ray';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.XRayDiagnosisLabel = uilabel(app.UIFigure);
            app.XRayDiagnosisLabel.Text = 'X-Ray Diagnosis';
            app.AnalyseXRayButton = uibutton(app.UIFigure, 'push');
            app.AnalyseXRayButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseXRayButtonPushed, true);
            app.AnalyseXRayButton.Text = 'Analyse X-Ray';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'Theta Value')
            ylabel(app.UIAxes2, 'Max Hough Transform')
            app.UIAxes2.XLim = [-100 100];
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = facture_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end