classdef tumor_detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReturnButton                 matlab.ui.control.Button
        UploadScanButton             matlab.ui.control.Button
        StandardMRIScanLabel         matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        OutlinedBrainTumorLabel      matlab.ui.control.Label
        UIAxes_3                     matlab.ui.control.UIAxes
        DetectTumorButton            matlab.ui.control.Button
        BrainMRIScanViewButtonGroup  matlab.ui.container.ButtonGroup
        SagittalButton               matlab.ui.control.RadioButton
        AxialButton                  matlab.ui.control.RadioButton
        CoronalButton                matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            %%Once the Button is pressed it will take the user to the 
            %%Main Menu screen and will delete the brain tumor dection window
            
            Main_Menu < matlab.apps.AppBase; %Opening scenario 1
            delete(app.UIFigure); %Closing Main Menu
        end

        % Button pushed function: UploadScanButton
        function UploadScanButtonPushed(app, event)
            global mri_img
            global bw_mri_scan
            
            %Allows the user to uploadthe brain MRI Scan they want a tumor
            %to be classified and detected in if present
          
            [rawname, rawpath] = uigetfile(('*.jpg'), 'Select Image Data');
            mri_scan_path = [rawpath, rawname];
            mri_img = imread(mri_scan_path); %Makes the inputted picture usable in the program
            mri_img = imresize(mri_img,[300, 300]);
            
            %%Saturation Factor Rules
            %If the cut is sagittal
            if isequal(app.BrainMRIScanViewButtonGroup.SelectedObject.Text, 'Sagittal')
                %disp('Sagittal')
                saturation_factor = 0.5;
            
                %if the cut is axial
            elseif isequal(app.BrainMRIScanViewButtonGroup.SelectedObject.Text, 'Axial')
                disp('Axial')
                saturation_factor = 0.7;
            
                %If the Cut is coronal
            elseif isequal(app.BrainMRIScanViewButtonGroup.SelectedObject.Text, 'Coronal')
                disp('Coronal')
                saturation_factor = 0.41;
                
            else
                
            end

            bw_mri_scan = im2bw(mri_img, saturation_factor); %Changes the image to black and white, also changes saturation
            
            imshow(mri_img, 'Parent',app.UIAxes); %Makes the Default MRi Image Appear in the App


        end

        % Callback function
        function SaturationFactorSliderValueChanged(app, event)
            value = app.SaturationFactorSlider.Value;
            
        end

        % Callback function
        function SaturationFactorSliderValueChanging(app, event)
            global mri_img
            global bw_mri_scan
            
            changingValue = event.Value;
          
            bw_mri_scan = im2bw(mri_img, app.DensityFactorSlider_2.Value); %Changes the image to black and white, also changes saturation
            
            imshow(mri_img, 'Parent',app.UIAxes); %Makes the Default MRi Image Appear in the App
            
        end

        % Button pushed function: DetectTumorButton
        function DetectTumorButtonPushed(app, event)
            global mri_img
            global bw_mri_scan
            
            label = bwlabel(bw_mri_scan);

            stats = regionprops(label,'Solidity', 'Area');
            
            density = [stats.Solidity]; %Takes the desnity at all the different points in the image
            area = [stats.Area]; %Takes the area at all the different points in the image
            
            %%Density Factor Rules
            %Sagittal Cut
            if isequal(app.BrainMRIScanViewButtonGroup.SelectedObject.Text, 'Sagittal')
                %disp('Sagittal')
                density_factor = 0.5;
                
                %if the cut is axial
            elseif isequal(app.BrainMRIScanViewButtonGroup.SelectedObject.Text, 'Axial')
                disp('Axial')
                density_factor = 0.5;
                
                %If the Cut is coronal
            elseif isequal(app.BrainMRIScanViewButtonGroup.SelectedObject.Text, 'Coronal')
                disp('Coronal')
                density_factor = 0.4;
                
            else
                
            end
            
            high_density_area = density > density_factor; %Defines all high dense areas (high desnse areas are indications of tumors on MRI)
            %The 0.5 value can change to a smaller percentage such that it can detect
            %early stage tumors
            max_area = max(area(high_density_area)); %Defines the border of the tumor given by high density points
            tumor_label = find(area == max_area);
            tumor = ismember(label, tumor_label); %Checks to see if info is valid
            
            %Checks to make sure that what is detected is a tumor and not a cyst, by
            %making sure the mass all has the same consistency in terms of density
            se = strel('square',5);
            tumor = imdilate(tumor, se);
            
            %imshow(tumor, 'Parent',app.UIAxes_2); %Makes the Excised brain tumor appear in the app
            
            %Drawing boundry around tumor
            [B,L] = bwboundaries(tumor,'noholes',app.UIAxes_3);
            imshow(mri_img, 'Parent',app.UIAxes_3); %Makes the outlines brain tumor appear in the app
            hold(app.UIAxes_3, 'on') 
                %for loop to create to boundry of the tumor
            for i =1:length(B)
                plot(app.UIAxes_3,B{i}(:,2), B{i}(:,i), 'y','linewidth',1.45)
            end
            
            hold(app.UIAxes_3, 'off') 
        end

        % Selection changed function: BrainMRIScanViewButtonGroup
        function BrainMRIScanViewButtonGroupSelectionChanged(app, event)
            selectedButton = app.BrainMRIScanViewButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.UploadScanButton = uibutton(app.UIFigure, 'push');
            app.UploadScanButton.ButtonPushedFcn = createCallbackFcn(app, @UploadScanButtonPushed, true);
            app.UploadScanButton.Text = {'Upload Scan'; ''};
            app.StandardMRIScanLabel = uilabel(app.UIFigure);
            app.StandardMRIScanLabel.Text = 'Standard MRI Scan';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.OutlinedBrainTumorLabel = uilabel(app.UIFigure);
            app.OutlinedBrainTumorLabel.Text = 'Outlined Brain Tumor';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.DetectTumorButton = uibutton(app.UIFigure, 'push');
            app.DetectTumorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectTumorButtonPushed, true);
            app.DetectTumorButton.Text = 'Detect Tumor';
            app.BrainMRIScanViewButtonGroup = uibuttongroup(app.UIFigure);
            app.BrainMRIScanViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BrainMRIScanViewButtonGroupSelectionChanged, true);
            app.BrainMRIScanViewButtonGroup.Title = 'Brain MRI Scan View';
            app.SagittalButton = uiradiobutton(app.BrainMRIScanViewButtonGroup);
            app.SagittalButton.Text = 'Sagittal';
            app.SagittalButton.Value = true;
            app.AxialButton = uiradiobutton(app.BrainMRIScanViewButtonGroup);
            app.AxialButton.Text = 'Axial';
            app.CoronalButton = uiradiobutton(app.BrainMRIScanViewButtonGroup);
            app.CoronalButton.Text = 'Coronal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tumor_detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end